classdef epsi_incoming_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PathsMenu                    matlab.ui.container.Menu
        SelectpathtodataMenu         matlab.ui.container.Menu
        SelectpathtoepsilibraryMenu  matlab.ui.container.Menu
        SubtractmeanCheckBox         matlab.ui.control.CheckBox
        s2CheckBox                   matlab.ui.control.CheckBox
        s1CheckBox                   matlab.ui.control.CheckBox
        t2CheckBox_2                 matlab.ui.control.CheckBox
        t1CheckBox_2                 matlab.ui.control.CheckBox
        PausedataButton              matlab.ui.control.Button
        StreamdataButton             matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
    end


    properties (Access = private)
        EpsiTimer
        Axes1
        Axes2
        Axes3
        Axes4
        Axes5
        Axes6
        Axes7
        Axes8
        Axes9
        Axes10
        DataDir % Description
        epsi_library_dir % Description
    end

    methods (Access = private)

        function EpsiTimerFcn(app, ~, event)

            obj = load('/Users/ncouto/Library/CloudStorage/GoogleDrive-ncouto@ucsd.edu/Shared drives/MOD data 2022 BLT3/05_processed_data/0812_sta08_d17_ef1_pc2/mat/EPSI22_08_13_041146.mat');
            obj.plot_properties = set_epsi_plot_properties;
            cols = obj.plot_properties.Colors;

            nSec = 1*60;
            nDay = nSec/(3600*24);

            % Define time axes for the plot
            time_array = make_time_arrays(obj);
            
            %% Axes1 - t1 and t2
            app.Axes1.NextPlot = 'replace';
            if app.SubtractmeanCheckBox.Value
                % t1 and t2 - differences from mean
                plot(app.Axes1,time_array.epsi,obj.epsi.t1_volt-nanmean(obj.epsi.t1_volt),'.','Color',cols.t1,'LineWidth',obj.plot_properties.LineWidth,'displayname',sprintf('t1 - %1.1f',nanmean(obj.epsi.t1_volt)));
                hold(app.Axes1,'on')
                plot(app.Axes1,time_array.epsi,obj.epsi.t2_volt-nanmean(obj.epsi.t2_volt),'.','Color',cols.t2,'LineWidth',obj.plot_properties.LineWidth,'displayname',sprintf('t2 - %1.1f',nanmean(obj.epsi.t2_volt)));

            elseif ~app.SubtractmeanCheckBox.Value
                % t1 and t2
                plot(app.Axes1,time_array.epsi,obj.epsi.t1_volt,'.','Color',cols.t1,'LineWidth',obj.plot_properties.LineWidth,'displayname','t1');
                hold(app.Axes1,'on')
                plot(app.Axes1,time_array.epsi,obj.epsi.t2_volt,'.','Color',cols.t2,'LineWidth',obj.plot_properties.LineWidth,'displayname','t2');
            end

                % s1 and s2
                plot(app.Axes2,time_array.epsi,obj.epsi.s1_volt,'.','Color',cols.s1,'LineWidth',obj.plot_properties.LineWidth,'displayname','s1')
                hold(app.Axes2,'on')
                plot(app.Axes2,time_array.epsi,obj.epsi.s2_volt,'.','Color',cols.s2,'LineWidth',obj.plot_properties.LineWidth,'displayname','s2')

                % a1
                plot(app.Axes3,time_array.epsi,obj.epsi.a1_g,'.','Color',cols.a1,'LineWidth',obj.plot_properties.LineWidth,'displayname','a1')

                % a2 and a3
                plot(app.Axes4,time_array.epsi,obj.epsi.a2_g,'.','Color',cols.a2,'LineWidth',obj.plot_properties.LineWidth,'displayname','a2')
                hold(app.Axes4,'on')
                plot(app.Axes4,time_array.epsi,obj.epsi.a3_g,'.','Color',cols.a3,'LineWidth',obj.plot_properties.LineWidth,'displayname','a3')

                % Add legends
                legend(app.Axes1,'location','northwest')
                legend(app.Axes2,'location','northwest')
                legend(app.Axes3,'location','northwest')
                legend(app.Axes4,'location','northwest')

            %% CTD PLOTS

            if isclassfield(obj,'ctd')
                if ~isempty(obj.ctd)

                    % Fall speed (dPdt)
                    plot(ax(8),time_array.ctd,movmean(obj.ctd.dPdt,100),'.','Color',cols.dPdt,'LineWidth',obj.plot_properties.LineWidth)

                    % Pressure and altimiter
                    plot(ax(9),time_array.ctd,obj.ctd.P,'.','Color',cols.P)
                    if isclassfield(obj,'alt') && ~isempty(obj.alt)
                        plot(ax(10),time_array.alt,obj.alt.dst,'.','Color',cols.alt)
                    end

                end
            end

            %% VNAV PLOTS

            if isclassfield(obj,'vnav')
                if ~isempty(obj.vnav)

                    % Gyro z
                    plot(ax(5),time_array.vnav,obj.vnav.gyro(:,3),'.','Color',cols.gyro3,'LineWidth',obj.plot_properties.LineWidth,'displayname','z')

                    % Gyro x
                    plot(ax(6),time_array.vnav,obj.vnav.gyro(:,1),'.','Color',cols.gyro1,'LineWidth',obj.plot_properties.LineWidth,'displayname','x')
                    hold(ax(6),'on')
                    plot(ax(6),time_array.vnav,obj.vnav.gyro(:,2),'.','Color',cols.gyro2,'LineWidth',obj.plot_properties.LineWidth,'displayname','y')

                    % Compass
                    plot(ax(7),time_array.vnav,obj.vnav.compass(:,1),'.','Color',cols.compass1,'LineWidth',obj.plot_properties.LineWidth);

                    % Add legends
                    legend(ax(5),'location','northwest')
                    legend(ax(6),'location','northwest')

                end
            end

            %% GPS DATA
            if isclassfield(obj,'gps')
                if ~isempty(obj.gps)
                    % Find the last non-nan lat/lon and plot a few decimals
                    not_nan_lat = obj.gps.latitude(~isnan(obj.gps.latitude) & ~isnan(obj.gps.longitude));
                    not_nan_lon = obj.gps.longitude(~isnan(obj.gps.latitude) & ~isnan(obj.gps.longitude));
                    if ~isempty(not_nan_lat)
                        lat = not_nan_lat(end);
                        lon = not_nan_lon(end);
                    else
                        lat = nan;
                        lon = nan;
                    end

                    annotation(gcf,'textbox',...
                        [0.8093 0.9754 0.2212 0.0288],...
                        'String',{sprintf('lat = %03.4f',lat),sprintf('lon = %03.4f',lon)},...
                        'LineStyle','none',...
                        'FontSize',14,...
                        'FontName',obj.plot_properties.FontName,...
                        'FitBoxToText','off');
                end
            end


            %% AXES

            % Time axes label and limits with 10-sec tick marks
            if isfield(obj.epsi,'dnum') && ~all(isnan(obj.epsi.dnum)) && replaceData
                sec10 = 10/(3600*24);
                % If plotting in realtime, limit view
                [ax(:).XTick] = deal(fliplr(nanmax(obj.epsi.dnum):-sec10:nanmax(obj.epsi.dnum)-nDay));
                [ax(:).XLim] = deal([nanmax(obj.epsi.dnum)-nDay,nanmax(obj.epsi.dnum)]);
                try
                    datetick(ax(10),'x','HH:MM:SS','keepticks')
                    ax(10).XLabel.String = 'HH:MM:SS';
                catch
                end
            elseif ~replaceData
                [ax(:).XLim] = deal([nanmin(obj.epsi.dnum),nanmax(obj.epsi.dnum)]);
                try
                    datetick(ax(10),'x','MM:SS','keepticks')
                    ax(10).XLabel.String = 'MM:SS';
                catch
                end
            else
                ax(10).XLabel.String = 'epsitime (s)';
            end

            % Title, ylabels, and font size
            if isfield(obj,'Meta_Data')
                title(ax(1),sprintf('%s-%s-%s',strrep(obj.Meta_Data.mission,'_','\_'),...
                    strrep(obj.Meta_Data.vehicle_name,'_','\_'),...
                    strrep(obj.Meta_Data.deployment,'_','\_')));
            end

            % Labels
            [ax(1:9).XTickLabel]=deal('');

            ylabel(ax(1),'FPO7 [Volt]');
            ylabel(ax(2),'Shear [Volt]');
            ylabel(ax(3),'Accel [g]');
            ylabel(ax(5),'Gyro []');
            ylabel(ax(7),'Compass []');
            ylabel(ax(8),'dPdt [dbar/s]');
            ylabel(ax(9),'P [dbar]');
            ylabel(ax(10),'altimeter');

            [ax(:).FontSize] = deal(obj.plot_properties.FontSize);
            [ax(:).FontName] = deal(obj.plot_properties.FontName);

            % Right-hand y-axes
            ax(4).YAxisLocation = 'right';
            ax(6).YAxisLocation = 'right';
            ax(10).YAxisLocation = 'right';
            ax(10).Color = 'none';
            ax(10).YLim = [0 30];
            ax(10).YGrid = 'on';

            % Axes colors
            ax(9).YColor = cols.P;
            ax(10).YColor = cols.alt;

            % Flip pressure axis
            ax(9).YDir = 'reverse';

            % Link x-axes and add grid
            linkprop([ax(:)],'xlim');
            [ax(:).XGrid] = deal('on');

            % Y-Limits
            % for iAx=1:length(ax)
            %     dataMed = nanmedian([ax(iAx).Children(:).YData]);
            %     dataStd = nanstd([ax(iAx).Children(:).YData]);
            %     try %Try to reset the y-limits. Sometimes it fails if all data is nan
            %     ax(iAx).YLim = [dataMed-1.5*dataStd,dataMed+1.5*dataStd];
            %     catch
            %     end
            % end

            % Bring alt axes to the front
            axes(ax(10))

            % Set font name and size
            [ax(:).FontSize] = deal(obj.plot_properties.FontSize);
            [ax(:).FontName] = deal(obj.plot_properties.FontName);
            function [time_array] = make_time_arrays(obj)

                field_names = {'epsi','ctd','alt','vnav','gps'};

                for iField = 1:length(field_names)

                    classCondition = isclassfield(obj,field_names{iField}) && ~isempty(obj.(field_names{iField}));
                    if classCondition
                        structCondition = ~isempty(obj.(field_names{iField}));
                        if structCondition
                            % Do you have dnum or seconds?
                            if isfield(obj.(field_names{iField}),'dnum')
                                time_array.(field_names{iField}) = obj.(field_names{iField}).dnum;
                            else
                                time_array.(field_names{iField}) = obj.(field_names{iField}).time_s;
                            end
                        end
                    end %end if classCondition
                end %end loop through fields
            end %end make_time_arrays
        end %end EpsiTimerFcn
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Add paths to epsi library
            app.epsi_library_dir = 'Epsi library: ~/GitHub/EPSILOMETER';
            annotation(app.Panel,'textbox',[0.1,0.95,0.8,0.05],...
                'string',app.epsi_library_dir,...
                'linestyle','none',...
                'FontName','Helvetica',...
                'FontSize',12);

            annotation(app.Panel,'textbox',[0.1,0.94,0.8,0.05],...
                'string','Data dir: ',...
                'linestyle','none',...
                'FontName','Helvetica',...
                'FontSize',12);

            % Create timer object
            app.EpsiTimer = timer(...
                'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
                'Period', 5, ...                       % Period is 5 seconds
                'BusyMode', 'queue',...                % Queue timer callbacks when busy
                'TimerFcn', @app.EpsiTimerFcn);   % Callback that runs every period

            createAxes(app)
        end

        function createAxes(app)
            % If you create axes within the app designer environment, you
            % don't have much control over the size. As a workaround, initialize the
            % axes in the startup function.
            % Create axes
            app.Axes1 = axes(app.Panel,'position',[0.1000    0.8507    0.8700    0.1143]);
            app.Axes2 = axes(app.Panel,'position',[0.1000    0.7164    0.8700    0.1143]);
            app.Axes3 = axes(app.Panel,'position',[0.1000    0.6393    0.8700    0.0571]);
            app.Axes4 = axes(app.Panel,'position',[0.1000    0.5821    0.8700    0.0571]);
            app.Axes5 = axes(app.Panel,'position',[0.1000    0.5050    0.8700    0.0571]);
            app.Axes6 = axes(app.Panel,'position',[0.1000    0.4479    0.8700    0.0571]);
            app.Axes7 = axes(app.Panel,'position',[0.1000    0.3136    0.8700    0.1143]);
            app.Axes8 = axes(app.Panel,'position',[0.1000    0.1793    0.8700    0.1143]);
            app.Axes9 = axes(app.Panel,'position',[0.1000    0.0450    0.8700    0.1143]);
            app.Axes10 = axes(app.Panel,'position',[0.1000    0.0450   0.8700    0.1143]);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            stop(app.EpsiTimer);
            delete(app.EpsiTimer);
            delete(app);
        end

        % Button pushed function: StreamdataButton
        function StreamdataButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.EpsiTimer.Running, 'off')
                % Start timer
                start(app.EpsiTimer);
            end
        end

        % Menu selected function: SelectpathtoepsilibraryMenu
        function SelectpathtoepsilibraryMenuSelected(app, event)
            app.epsi_library_dir = uigetdir();
            annotation(app.Panel,'textbox',[0.1,0.95,0.8,0.05],...
                'string',['Epsi library: ',app.epsi_library_dir],...
                'linestyle','none',...
                'FontName','Helvetica',...
                'FontSize',12);
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            figurePosition = app.UIFigure.Position;
            app.Panel.Position = [1 0 figurePosition(3)*770/900 figurePosition(4)];
            delete(app.Axes1)
            delete(app.Axes2)
            delete(app.Axes3)
            delete(app.Axes4)
            delete(app.Axes5)
            delete(app.Axes6)
            delete(app.Axes7)
            delete(app.Axes8)
            delete(app.Axes9)
            delete(app.Axes10)
            createAxes(app);
        end

        % Value changed function: SubtractmeanCheckBox
        function SubtractmeanCheckBoxValueChanged(app, event)
            value = app.SubtractmeanCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.PathsMenu = uimenu(app.UIFigure);
            app.PathsMenu.Text = 'Paths';
            app.SelectpathtodataMenu = uimenu(app.PathsMenu);
            app.SelectpathtodataMenu.Text = 'Select path to data';
            app.SelectpathtoepsilibraryMenu = uimenu(app.PathsMenu);
            app.SelectpathtoepsilibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectpathtoepsilibraryMenuSelected, true);
            app.SelectpathtoepsilibraryMenu.Text = 'Select path to epsi library';
            app.Panel = uipanel(app.UIFigure);
            app.StreamdataButton = uibutton(app.UIFigure, 'push');
            app.StreamdataButton.ButtonPushedFcn = createCallbackFcn(app, @StreamdataButtonPushed, true);
            app.StreamdataButton.Text = 'Stream data';
            app.PausedataButton = uibutton(app.UIFigure, 'push');
            app.PausedataButton.Text = 'Pause data';
            app.t1CheckBox_2 = uicheckbox(app.UIFigure);
            app.t1CheckBox_2.Text = 't1';
            app.t2CheckBox_2 = uicheckbox(app.UIFigure);
            app.t2CheckBox_2.Text = 't2';
            app.s1CheckBox = uicheckbox(app.UIFigure);
            app.s1CheckBox.Text = 's1';
            app.s2CheckBox = uicheckbox(app.UIFigure);
            app.s2CheckBox.Text = 's2';
            app.SubtractmeanCheckBox = uicheckbox(app.UIFigure);
            app.SubtractmeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractmeanCheckBoxValueChanged, true);
            app.SubtractmeanCheckBox.Text = 'Subtract mean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epsi_incoming_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end