classdef epsi_incoming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EpsiIncomingUIFigure     matlab.ui.Figure
        DataMenu                 matlab.ui.container.Menu
        SelectDataDirectoryMenu  matlab.ui.container.Menu
        SubtractmeanCheckBox     matlab.ui.control.CheckBox
        t2CheckBox_2             matlab.ui.control.CheckBox
        t1CheckBox_2             matlab.ui.control.CheckBox
        s2CheckBox               matlab.ui.control.CheckBox
        s1CheckBox               matlab.ui.control.CheckBox
        StreamdataButton         matlab.ui.control.Button
        PausedataButton          matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_1                 matlab.ui.control.UIAxes
        UIAxes_7                 matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        EpsiTimer % Timer object
    end

    methods (Access = private)

        function EpsiTimerFcn(app, ~, event)


            obj = load('/Users/ncouto/Library/CloudStorage/GoogleDrive-ncouto@ucsd.edu/Shared drives/MOD data 2022 BLT3/05_processed_data/0812_sta08_d17_ef1_pc2/mat/EPSI22_08_13_041146.mat');
            obj.plot_properties = set_epsi_plot_properties;
            cols = obj.plot_properties.Colors;

            nSec = 1*60;
            nDay = nSec/(3600*24);

            % Define time axes for the plot
            time_array = make_time_arrays(obj);

            %% UIAxes - t1 and t2
            app.UIAxes_1.NextPlot = 'replace';
            if app.SubtractmeanCheckBox.Value
                % t1 and t2 - differences from mean
                plot(app.UIAxes_1,time_array.epsi,obj.epsi.t1_volt-nanmean(obj.epsi.t1_volt),'.','Color',cols.t1,'LineWidth',obj.plot_properties.LineWidth,'displayname',sprintf('t1 - %1.1f',nanmean(obj.epsi.t1_volt)));
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,time_array.epsi,obj.epsi.t2_volt-nanmean(obj.epsi.t2_volt),'.','Color',cols.t2,'LineWidth',obj.plot_properties.LineWidth,'displayname',sprintf('t2 - %1.1f',nanmean(obj.epsi.t2_volt)));

            elseif ~app.SubtractmeanCheckBox.Value
                % t1 and t2
                plot(app.UIAxes_1,time_array.epsi,obj.epsi.t1_volt,'.','Color',cols.t1,'LineWidth',obj.plot_properties.LineWidth,'displayname','t1');
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,time_array.epsi,obj.epsi.t2_volt,'.','Color',cols.t2,'LineWidth',obj.plot_properties.LineWidth,'displayname','t2');
            end

            %% UIAxes_2 s1 and s2
            app.UIAxes_2.NextPlot = 'replace';
            plot(app.UIAxes_2,time_array.epsi,obj.epsi.s1_volt,'.','Color',cols.s1,'LineWidth',obj.plot_properties.LineWidth,'displayname','s1')
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,time_array.epsi,obj.epsi.s2_volt,'.','Color',cols.s2,'LineWidth',obj.plot_properties.LineWidth,'displayname','s2')

            % a1
            app.UIAxes_3.NextPlot = 'replace';
            plot(app.UIAxes_3,time_array.epsi,obj.epsi.a1_g,'.','Color',cols.a1,'LineWidth',obj.plot_properties.LineWidth,'displayname','a1')

            % a2 and a3
            app.UIAxes_4.NextPlot = 'replace';
            plot(app.UIAxes_4,time_array.epsi,obj.epsi.a2_g,'.','Color',cols.a2,'LineWidth',obj.plot_properties.LineWidth,'displayname','a2')
            hold(app.UIAxes_4,'on')
            plot(app.UIAxes_4,time_array.epsi,obj.epsi.a3_g,'.','Color',cols.a3,'LineWidth',obj.plot_properties.LineWidth,'displayname','a3')

            % Add legends
            legend(app.UIAxes_1,'location','northwest')
            legend(app.UIAxes_2,'location','northwest')
            legend(app.UIAxes_3,'location','northwest')
            legend(app.UIAxes_4,'location','northwest')

            %% Adjust axes properties
            app.UIAxes_1.FontName = 'Helvetica';
            app.UIAxes_1.FontSize = 12;
            app.UIAxes_1.LineWidth = 0.5;
            app.UIAxes_1.XTickLabel = '';

            app.UIAxes_2.FontName = 'Helvetica';
            app.UIAxes_2.FontSize = 12;
            app.UIAxes_2.LineWidth = 0.5;
            app.UIAxes_2.XTickLabel = '';

            app.UIAxes_3.FontName = 'Helvetica';
            app.UIAxes_3.FontSize = 12;
            app.UIAxes_3.LineWidth = 0.5;
            app.UIAxes_3.XTickLabel = '';

            app.UIAxes_4.FontName = 'Helvetica';
            app.UIAxes_4.FontSize = 12;
            app.UIAxes_4.LineWidth = 0.5;
            app.UIAxes_4.XTickLabel = '';

            function [time_array] = make_time_arrays(obj)

                field_names = {'epsi','ctd','alt','vnav','gps'};

                for iField = 1:length(field_names)

                    classCondition = isclassfield(obj,field_names{iField}) && ~isempty(obj.(field_names{iField}));
                    if classCondition
                        structCondition = ~isempty(obj.(field_names{iField}));
                        if structCondition
                            % Do you have dnum or seconds?
                            if isfield(obj.(field_names{iField}),'dnum')
                                time_array.(field_names{iField}) = obj.(field_names{iField}).dnum;
                            else
                                time_array.(field_names{iField}) = obj.(field_names{iField}).time_s;
                            end
                        end
                    end %end if classCondition
                end %end loop through fields
            end %end make_time_arrays
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Add paths to epsi library
            goto('epsilometer')

            % Create timer object
            app.EpsiTimer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', 5, ...                       % Period is 5 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.EpsiTimerFcn);   % Callback that runs every period
        end

        % Button pushed function: StreamdataButton
        function StreamdataButtonPushed(app, event)

            % If timer is not running, start it
            if strcmp(app.EpsiTimer.Running, 'off')
                % Start timer
                start(app.EpsiTimer);
            end
        end

        % Menu selected function: SelectDataDirectoryMenu
        function SelectDataDirectoryMenuSelected(app, event)
            data_dir = uigetdir();
        end

        % Close request function: EpsiIncomingUIFigure
        function EpsiIncomingUIFigureCloseRequest(app, event)
             % Stop timer, then delete timer and figure
            stop(app.EpsiTimer);
            delete(app.EpsiTimer);
            delete(app);
        end

        % Value changed function: SubtractmeanCheckBox
        function SubtractmeanCheckBoxValueChanged(app, event)
            value = app.SubtractmeanCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EpsiIncomingUIFigure = uifigure('Visible', 'off');
            app.EpsiIncomingUIFigure.Name = 'Epsi Incoming';
            app.EpsiIncomingUIFigure.CloseRequestFcn = createCallbackFcn(app, @EpsiIncomingUIFigureCloseRequest, true);
            app.DataMenu = uimenu(app.EpsiIncomingUIFigure);
            app.DataMenu.Text = 'Data';
            app.SelectDataDirectoryMenu = uimenu(app.DataMenu);
            app.SelectDataDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataDirectoryMenuSelected, true);
            app.SelectDataDirectoryMenu.Text = 'Select Data Directory';
            app.UIAxes_6 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_7 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_1 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTickLabel = '';
            app.UIAxes_4 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_5 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_3 = uiaxes(app.EpsiIncomingUIFigure);
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTickLabel = '';
            app.PausedataButton = uibutton(app.EpsiIncomingUIFigure, 'push');
            app.PausedataButton.Text = 'Pause data';
            app.StreamdataButton = uibutton(app.EpsiIncomingUIFigure, 'push');
            app.StreamdataButton.ButtonPushedFcn = createCallbackFcn(app, @StreamdataButtonPushed, true);
            app.StreamdataButton.Text = {'Stream data'; ''};
            app.s1CheckBox = uicheckbox(app.EpsiIncomingUIFigure);
            app.s1CheckBox.Text = 's1';
            app.s2CheckBox = uicheckbox(app.EpsiIncomingUIFigure);
            app.s2CheckBox.Text = {'s2'; ''};
            app.t1CheckBox_2 = uicheckbox(app.EpsiIncomingUIFigure);
            app.t1CheckBox_2.Text = 't1';
            app.t2CheckBox_2 = uicheckbox(app.EpsiIncomingUIFigure);
            app.t2CheckBox_2.Text = 't2';
            app.SubtractmeanCheckBox = uicheckbox(app.EpsiIncomingUIFigure);
            app.SubtractmeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractmeanCheckBoxValueChanged, true);
            app.SubtractmeanCheckBox.Text = 'Subtract mean';
            % Show the figure after all components are created
            app.EpsiIncomingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epsi_incoming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EpsiIncomingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EpsiIncomingUIFigure)
        end
    end
end